classdef ihcp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ihcpUIFigure                   matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        SetcurrentvaluesasdefaultMenu  matlab.ui.container.Menu
        ResettodefaultMenu             matlab.ui.container.Menu
        MattLenahan2023Menu            matlab.ui.container.Menu
        ThermocouplepositionsPanel     matlab.ui.container.Panel
        T_Cu3pos                       matlab.ui.control.NumericEditField
        T_Cu3EditFieldLabel            matlab.ui.control.Label
        T_Inco2pos                     matlab.ui.control.NumericEditField
        T_Inco2EditFieldLabel          matlab.ui.control.Label
        T_Inco1pos                     matlab.ui.control.NumericEditField
        T_Inco1EditFieldLabel          matlab.ui.control.Label
        T_Cu2pos                       matlab.ui.control.NumericEditField
        T_Cu2EditFieldLabel            matlab.ui.control.Label
        GeometryPanel                  matlab.ui.container.Panel
        lengthCu2                      matlab.ui.control.NumericEditField
        LengthCu2EditFieldLabel        matlab.ui.control.Label
        lengthInco2                    matlab.ui.control.NumericEditField
        LengthInco2EditFieldLabel      matlab.ui.control.Label
        lengthH25                      matlab.ui.control.NumericEditField
        LengthH25EditFieldLabel        matlab.ui.control.Label
        lengthInco1                    matlab.ui.control.NumericEditField
        LengthInco1EditFieldLabel      matlab.ui.control.Label
        lengthCu1                      matlab.ui.control.NumericEditField
        LengthCu1EditFieldLabel        matlab.ui.control.Label
        BrowseButton                   matlab.ui.control.Button
        ParametersPanel                matlab.ui.container.Panel
        plotCheck                      matlab.ui.control.CheckBox
        dt                             matlab.ui.control.NumericEditField
        dxLabel_2                      matlab.ui.control.Label
        dx                             matlab.ui.control.NumericEditField
        dxLabel                        matlab.ui.control.Label
        epsilonEditField               matlab.ui.control.NumericEditField
        epsilonEditFieldLabel          matlab.ui.control.Label
        maxIterSpinner                 matlab.ui.control.Spinner
        maxIterSpinnerLabel            matlab.ui.control.Label
        RTOLerrorEditField             matlab.ui.control.NumericEditField
        RTOLerrorEditFieldLabel        matlab.ui.control.Label
        RTOLhEditField                 matlab.ui.control.NumericEditField
        RTOLhEditFieldLabel            matlab.ui.control.Label
        hInitialEditField              matlab.ui.control.NumericEditField
        hInitialEditFieldLabel         matlab.ui.control.Label
        rSpinner                       matlab.ui.control.Spinner
        rSpinnerLabel                  matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        selectedfile                   matlab.ui.control.EditField
        SelectedfileEditFieldLabel     matlab.ui.control.Label
    end

    
    methods (Access = private)

%         function checkconvergence(app)
%             % lookup materials
%             mat1u = materiallookup(app.up1mat.Value);
%             mat2u = materiallookup(app.up2mat.Value);
%             mat1c = materiallookup(app.cent1mat.Value);
%             mat2c = materiallookup(app.cent2mat.Value);
%             mat3c = materiallookup(app.cent3mat.Value);
%             mat1d = materiallookup(app.down1mat.Value);
%             mat2d = materiallookup(app.down2mat.Value);
% 
%             T = 25; % room temp deg C
%             % calculate all tau
%             % upstream
%             k1u = mat1u(1, 1) + T*mat1u(1, 2) + T^2*mat1u(1, 3);
%             c_p1u = mat1u(2, 1) + T*mat1u(2, 2) + T^2*mat1u(2, 3);
%             rho1u = mat1u(3, 1) + T*mat1u(3, 2) + T^2*mat1u(3, 3);
%             tau1u = k1u*rho1u^-1*c_p1u^-1*app.dt.Value...
%                 *app.dx.Value^-2;
%             
%             k2u = mat2u(1, 1) + T*mat2u(1, 2) + T^2*mat2u(1, 3);
%             c_p2u = mat2u(2, 1) + T*mat2u(2, 2) + T^2*mat2u(2, 3);
%             rho2u = mat2u(3, 1) + T*mat2u(3, 2) + T^2*mat2u(3, 3);
%             tau2u = k2u*rho2u^-1*c_p2u^-1*app.dt.Value...
%                 *app.dx.Value^-2;
% 
%             if tau1u > .5 || tau2u > .5
%                 app.upconv.Color = [1, 0, 0]; % red
%             else
%                 app.upconv.Color = [0, 1, 0]; % green
%             end
% 
%             % central
%             k1c = mat1c(1, 1) + T*mat1c(1, 2) + T^2*mat1c(1, 3);
%             c_p1c = mat1c(2, 1) + T*mat1c(2, 2) + T^2*mat1c(2, 3);
%             rho1c = mat1c(3, 1) + T*mat1c(3, 2) + T^2*mat1c(3, 3);
%             tau1c = k1c*rho1c^-1*c_p1c^-1*app.centdt.Value...
%                 *app.centdx.Value^-2;
% 
%             k2c = mat2c(1, 1) + T*mat2c(1, 2) + T^2*mat2c(1, 3);
%             c_p2c = mat2c(2, 1) + T*mat2c(2, 2) + T^2*mat2c(2, 3);
%             rho2c = mat2c(3, 1) + T*mat2c(3, 2) + T^2*mat2c(3, 3);
%             tau2c = k2c*rho2c^-1*c_p2c^-1*app.centdt.Value...
%                 *app.centdx.Value^-2;
% 
%             k3c = mat3c(1, 1) + T*mat3c(1, 2) + T^2*mat3c(1, 3);
%             c_p3c = mat3c(2, 1) + T*mat3c(2, 2) + T^2*mat3c(2, 3);
%             rho3c = mat3c(3, 1) + T*mat3c(3, 2) + T^2*mat3c(3, 3);
%             tau3c = k3c*rho3c^-1*c_p3c^-1*app.centdt.Value...
%                 *app.centdx.Value^-2;
% 
%             if tau1c > .5 || tau2c > .5 || tau3c > .5
%                 app.centconv.Color = [1, 0, 0];
%             else
%                 app.centconv.Color = [0, 1, 0];
%             end
% 
%             % downstream
%             k1d = mat1d(1, 1) + T*mat1d(1, 2) + T^2*mat1d(1, 3);
%             c_p1d = mat1d(2, 1) + T*mat1d(2, 2) + T^2*mat1d(2, 3);
%             rho1d = mat1d(3, 1) + T*mat1d(3, 2) + T^2*mat1d(3, 3);
%             tau1d = k1d*rho1d^-1*c_p1d^-1*app.downdt.Value...
%                 *app.downdx.Value^-2;
%             
%             k2d = mat2d(1, 1) + T*mat2d(1, 2) + T^2*mat2d(1, 3);
%             c_p2d = mat2d(2, 1) + T*mat2d(2, 2) + T^2*mat2d(2, 3);
%             rho2d = mat2d(3, 1) + T*mat2d(3, 2) + T^2*mat2d(3, 3);
%             tau2d = k2d*rho2d^-1*c_p2d^-1*app.downdt.Value...
%                 *app.downdx.Value^-2;
% 
%             if tau1d > .5 || tau2d > .5
%                 app.downconv.Color = [1, 0, 0];
%             else
%                 app.downconv.Color = [0, 1, 0];
%             end
% 
%             if any([tau1u tau1c tau1d tau2u tau2c tau2d tau3c] > 0.5)
%                 app.RunButton.Enable = "off";
%             elseif not(isempty(app.selectedfile.Value)) % if file picked
%                 app.RunButton.Enable = "on";
%             end
% 
%         end

        function getfile(app)
            % fix for lost focus
            f = figure('Position', [-100 -100 0 0]); % off-screen
            % file picker
            [file, path] = uigetfile({'*.*'; '*.csv'; '*.dat'; ...
                '*.txt'}, "Open...");
            delete(f);
            % if file selected i.e. user doesn't cancel
            if file ~= 0
                app.selectedfile.Value = fullfile(path, file);
                app.RunButton.Enable = "on";
                % prepare
                prepdata(fullfile(path, file))
            end
        end

        function savestate(app)

            state.T_Cu2pos.Value = app.T_Cu2pos.Value;
            state.T_Inco1pos.Value = app.T_Inco1pos.Value;
            state.T_Inco2pos.Value = app.T_Inco2pos.Value;
            state.T_Cu3pos.Value = app.T_Cu3pos.Value;

            state.lengthCu1.Value = app.lengthCu1.Value;
            state.lengthInco1.Value = app.lengthInco1.Value;
            state.lengthH25.Value = app.lengthH25.Value;
            state.lengthInco2.Value = app.lengthInco2.Value;
            state.lengthCu2.Value = app.lengthCu2.Value;

            state.dx.Value = app.dx.Value;
            state.dt.Value = app.dt.Value;

            state.rSpinner.Value = app.rSpinner.Value;
            state.epsilonEditField.Value = app.epsilonEditField.Value;
            state.hInitialEditField.Value = app.hInitialEditField.Value;
            state.RTOLqhEditField.Value = app.RTOLhEditField.Value;
            state.RTOLerrorEditField.Value = app.RTOLerrorEditField.Value;
            state.maxIterSpinner.Value = app.maxIterSpinner.Value;

            state.plotCheck.Value = app.plotCheck.Value;

            save("defaultState.mat", "state")
        end

        function loadstate(app)
            load("defaultState.mat", "state")

            app.T_Cu2pos.Value = state.T_Cu2pos.Value;
            app.T_Inco1pos.Value = state.T_Inco1pos.Value;
            app.T_Inco2pos.Value = state.T_Inco2pos.Value;
            app.T_Cu3pos.Value = state.T_Cu3pos.Value;

            app.lengthCu1.Value = state.lengthCu1.Value;
            app.lengthInco1.Value = state.lengthInco1.Value;
            app.lengthH25.Value = state.lengthH25.Value;
            app.lengthInco2.Value = state.lengthInco2.Value;
            app.lengthCu2.Value = state.lengthCu2.Value;

            app.dt.Value = state.dt.Value;
            app.dx.Value = state.dx.Value;

            app.rSpinner.Value = state.rSpinner.Value;
            app.epsilonEditField.Value = state.epsilonEditField.Value;
            app.hInitialEditField.Value = state.hInitialEditField.Value;
            app.RTOLhEditField.Value = state.RTOLqhEditField.Value;
            app.RTOLerrorEditField.Value = state.RTOLerrorEditField.Value;
            app.maxIterSpinner.Value = state.maxIterSpinner.Value;

            app.plotCheck.Value = state.plotCheck.Value;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % button icon
            app.RunButton.Icon = fullfile(matlabroot,'ui','install',...
                'installer_login','images','toolstrip','Run_24.png');
            try loadstate(app)
            catch
                uialert(app.ihcpUIFigure, "No defaults found.", "Warning", Icon="warning")
            end
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            getfile(app)
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % gather all entered data
            % filename
            filename = app.selectedfile.Value;
           
            % geometry
            geometry = cell(6, 1);
            geometry{1} = [app.lengthCu1.Value app.lengthInco1.Value ...
                app.lengthH25.Value app.lengthInco2.Value ...
                app.lengthCu2.Value];
            geometry{2} = sum(geometry{1}, "all");
            geometry{3} = app.dx.Value;
            geometry{4} = round(geometry{1}./geometry{3});
            if mod(geometry{1}, geometry{3})
                uialert(app.ihcpUIFigure, "Length(s) not divisible by dx", "Error", Icon="error")
                return
            end
            geometry{5} = sum(geometry{4});
            positions = [app.T_Cu2pos.Value app.T_Inco1pos.Value ...
                app.T_Inco2pos.Value app.T_Cu3pos.Value];
            geometry{6} = round(.5+(positions)/geometry{3});
            if any(mod(.5+(positions), geometry{3}))
                uialert(app.ihcpUIFigure, "TC position(s) not in node centre(s)", "Error", Icon="error")
                return
            end

            % materials
            materials = cat(3, materiallookup("Copper"), ...
                materiallookup("Inconel 718"), ...
                materiallookup("Haynes 25"), ...
                materiallookup("Inconel 718"), ...
                materiallookup("Copper"));

            % parameters
            parameters.r = app.rSpinner.Value;
            parameters.dt = app.dt.Value;
            parameters.epsilon = app.epsilonEditField.Value;
            parameters.hInitial = app.hInitialEditField.Value;
            parameters.maxiter = app.maxIterSpinner.Value;
            parameters.RTOLh = app.RTOLhEditField.Value;
            parameters.RTOLerror = app.RTOLerrorEditField.Value;

            inverse(app, filename, geometry, materials, parameters)

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            getfile(app)
        end

        % Menu selected function: SetcurrentvaluesasdefaultMenu
        function SetcurrentvaluesasdefaultMenuSelected(app, event)
            savestate(app)
        end

        % Menu selected function: ResettodefaultMenu
        function ResettodefaultMenuSelected(app, event)
            loadstate(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ihcpUIFigure = uifigure('Visible', 'off');
            app.ihcpUIFigure.Name = 'ihcp';
            app.FileMenu = uimenu(app.ihcpUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Accelerator = 'o';
            app.OpenMenu.Text = 'Open...';
            app.SetcurrentvaluesasdefaultMenu = uimenu(app.FileMenu);
            app.SetcurrentvaluesasdefaultMenu.MenuSelectedFcn = createCallbackFcn(app, @SetcurrentvaluesasdefaultMenuSelected, true);
            app.SetcurrentvaluesasdefaultMenu.Accelerator = 'd';
            app.SetcurrentvaluesasdefaultMenu.Text = 'Set current values as default';
            app.ResettodefaultMenu = uimenu(app.FileMenu);
            app.ResettodefaultMenu.MenuSelectedFcn = createCallbackFcn(app, @ResettodefaultMenuSelected, true);
            app.ResettodefaultMenu.Accelerator = 'r';
            app.ResettodefaultMenu.Text = 'Reset to default';
            app.MattLenahan2023Menu = uimenu(app.FileMenu);
            app.MattLenahan2023Menu.Enable = 'off';
            app.MattLenahan2023Menu.Text = 'Matt Lenahan 2023';
            app.SelectedfileEditFieldLabel = uilabel(app.ihcpUIFigure);
            app.SelectedfileEditFieldLabel.Text = 'Selected file';
            app.selectedfile = uieditfield(app.ihcpUIFigure, 'text');
            app.selectedfile.Editable = 'off';
            app.selectedfile.Placeholder = '<none>';
            app.RunButton = uibutton(app.ihcpUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.ParametersPanel = uipanel(app.ihcpUIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.rSpinnerLabel = uilabel(app.ParametersPanel);
            app.rSpinnerLabel.Text = 'r';
            app.rSpinner = uispinner(app.ParametersPanel);
            app.rSpinner.Limits = [1 50];
            app.rSpinner.Value = 5;
            app.hInitialEditFieldLabel = uilabel(app.ParametersPanel);
            app.hInitialEditFieldLabel.Text = 'hInitial';
            app.hInitialEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.hInitialEditField.Limits = [1 Inf];
            app.hInitialEditField.Value = 1000;
            app.RTOLhEditFieldLabel = uilabel(app.ParametersPanel);
            app.RTOLhEditFieldLabel.Text = 'RTOLh';
            app.RTOLhEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.RTOLhEditField.Limits = [0 Inf];
            app.RTOLhEditField.Value = 0.001;
            app.RTOLerrorEditFieldLabel = uilabel(app.ParametersPanel);
            app.RTOLerrorEditFieldLabel.Text = 'RTOLerror';
            app.RTOLerrorEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.RTOLerrorEditField.Limits = [0 Inf];
            app.RTOLerrorEditField.Value = 1e-06;
            app.maxIterSpinnerLabel = uilabel(app.ParametersPanel);
            app.maxIterSpinnerLabel.Text = 'maxIter';
            app.maxIterSpinner = uispinner(app.ParametersPanel);
            app.maxIterSpinner.Limits = [1 50];
            app.maxIterSpinner.Value = 20;
            app.epsilonEditFieldLabel = uilabel(app.ParametersPanel);
            app.epsilonEditFieldLabel.Text = 'epsilon';
            app.epsilonEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.epsilonEditField.Limits = [0 Inf];
            app.epsilonEditField.Value = 0.01;
            app.dxLabel = uilabel(app.ParametersPanel);
            app.dxLabel.Text = 'dx';
            app.dx = uieditfield(app.ParametersPanel, 'numeric');
            app.dx.Limits = [0 Inf];
            app.dx.Value = 0.000125;
            app.dxLabel_2 = uilabel(app.ParametersPanel);
            app.dxLabel_2.Text = 'dt';
            app.dt = uieditfield(app.ParametersPanel, 'numeric');
            app.dt.Limits = [0 Inf];
            app.dt.Value = 0.00025;
            app.plotCheck = uicheckbox(app.ParametersPanel);
            app.plotCheck.Text = 'Generate plot';
            app.BrowseButton = uibutton(app.ihcpUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.GeometryPanel = uipanel(app.ihcpUIFigure);
            app.GeometryPanel.Title = 'Geometry';
            app.LengthCu1EditFieldLabel = uilabel(app.GeometryPanel);
            app.LengthCu1EditFieldLabel.Text = 'Length Cu 1';
            app.lengthCu1 = uieditfield(app.GeometryPanel, 'numeric');
            app.LengthInco1EditFieldLabel = uilabel(app.GeometryPanel);
            app.LengthInco1EditFieldLabel.Text = 'Length Inco 1';
            app.lengthInco1 = uieditfield(app.GeometryPanel, 'numeric');
            app.LengthH25EditFieldLabel = uilabel(app.GeometryPanel);
            app.LengthH25EditFieldLabel.Text = 'Length H25 ';
            app.lengthH25 = uieditfield(app.GeometryPanel, 'numeric');
            app.LengthInco2EditFieldLabel = uilabel(app.GeometryPanel);
            app.LengthInco2EditFieldLabel.Text = 'Length Inco 2 ';
            app.lengthInco2 = uieditfield(app.GeometryPanel, 'numeric');
            app.LengthCu2EditFieldLabel = uilabel(app.GeometryPanel);
            app.LengthCu2EditFieldLabel.Text = 'Length Cu 2 ';
            app.lengthCu2 = uieditfield(app.GeometryPanel, 'numeric');
            app.ThermocouplepositionsPanel = uipanel(app.ihcpUIFigure);
            app.ThermocouplepositionsPanel.Tooltip = {'Referenced to start of first copper section input above'};
            app.ThermocouplepositionsPanel.Title = 'Thermocouple positions';
            app.T_Cu2EditFieldLabel = uilabel(app.ThermocouplepositionsPanel);
            app.T_Cu2EditFieldLabel.Text = 'T_Cu2';
            app.T_Cu2pos = uieditfield(app.ThermocouplepositionsPanel, 'numeric');
            app.T_Inco1EditFieldLabel = uilabel(app.ThermocouplepositionsPanel);
            app.T_Inco1EditFieldLabel.Text = 'T_Inco1';
            app.T_Inco1pos = uieditfield(app.ThermocouplepositionsPanel, 'numeric');
            app.T_Inco2EditFieldLabel = uilabel(app.ThermocouplepositionsPanel);
            app.T_Inco2EditFieldLabel.Text = 'T_Inco2';
            app.T_Inco2pos = uieditfield(app.ThermocouplepositionsPanel, 'numeric');
            app.T_Cu3EditFieldLabel = uilabel(app.ThermocouplepositionsPanel);
            app.T_Cu3EditFieldLabel.Text = 'T_Cu3';
            app.T_Cu3pos = uieditfield(app.ThermocouplepositionsPanel, 'numeric');
            % Show the figure after all components are created
            app.ihcpUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ihcp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ihcpUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.ihcpUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ihcpUIFigure)
        end
    end
end